classdef FirstApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MIA_Final                 matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        PreprocessingTab          matlab.ui.container.Tab
        Pre_FigurePanel           matlab.ui.container.Panel
        Image_origin              matlab.ui.control.Image
        Image_preprocess          matlab.ui.control.Image
        Label                     matlab.ui.control.Label
        Pre_ParametersPanel       matlab.ui.container.Panel
        FilteringPanel            matlab.ui.container.Panel
        SigmaLabel                matlab.ui.control.Label
        SigmaEditField            matlab.ui.control.NumericEditField
        AlphaEditFieldLabel       matlab.ui.control.Label
        AlphaEditField            matlab.ui.control.NumericEditField
        FilterDropDownLabel       matlab.ui.control.Label
        FilterDropDown            matlab.ui.control.DropDown
        Kernel_SizeSpinnerLabel   matlab.ui.control.Label
        Kernel_SizeSpinner        matlab.ui.control.Spinner
        FiltButton                matlab.ui.control.Button
        MorphologyPanel           matlab.ui.container.Panel
        BinarizeButtonGroup       matlab.ui.container.ButtonGroup
        Radio_Otsu                matlab.ui.control.RadioButton
        CustomizeButton           matlab.ui.control.RadioButton
        OpenButton                matlab.ui.control.Button
        LevelEditFieldLabel       matlab.ui.control.Label
        LevelEditField            matlab.ui.control.NumericEditField
        RadiusSpinnerLabel        matlab.ui.control.Label
        RadiusSpinner             matlab.ui.control.Spinner
        StrelDropDownLabel        matlab.ui.control.Label
        StrelDropDown             matlab.ui.control.DropDown
        SegmentationPanel         matlab.ui.container.Panel
        UpperBoundEditFieldLabel  matlab.ui.control.Label
        UpperBoundEditField       matlab.ui.control.NumericEditField
        LowerBoundEditFieldLabel  matlab.ui.control.Label
        LowerBoundEditField       matlab.ui.control.NumericEditField
        LocalBoundEditFieldLabel  matlab.ui.control.Label
        LocalBoundEditField       matlab.ui.control.NumericEditField
        LocalRadiusSpinnerLabel   matlab.ui.control.Label
        LocalRadiusSpinner        matlab.ui.control.Spinner
        OthersPanel               matlab.ui.container.Panel
        LoadButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        file_name;
        I;
        P_I;
        img_origin = imread('NCKU2_n20.jpg');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FiltButton
        function FiltButtonPushed(app, event)
            % msgbox(['Filter', string(app.FilterDropDown.Value),'Kernal_size:', string(app.Kernel_SizeSpinner.Value), 'Alpha:', string(app.AlphaEditField.Value), 'Sigma', string(app.SigmaEditField.Value)]);
            if isempty(app.I)
                errordlg('Process with empty image');
            else
                switch app.FilterDropDown.Value
                    case "Gaussian"
                        Gaussian_pic = imgaussfilt(app.I, app.SigmaEditField.Value);
                        app.P_I = Gaussian_pic;
                        app.Image_preprocess.ImageSource = repmat(app.P_I, 1, 1, 3);
                    case "Mean"
                        radius_kernel = fspecial('disk', app.Kernel_SizeSpinner.Value);
                        Box_pic = imfilter(app.I, radius_kernel, 'replicate');
                        app.P_I = Box_pic;
                        app.Image_preprocess.ImageSource = repmat(app.P_I, 1, 1, 3);
                    case "Median"
                        Meadian_pic = medfilt2(app.I, [app.Kernel_SizeSpinner.Value app.Kernel_SizeSpinner.Value]);
                        app.P_I = Meadian_pic;
                        app.Image_preprocess.ImageSource = repmat(app.P_I, 1, 1, 3);
                    case "Wiener"
                        Wiener_pic = wiener2(app.I, [app.Kernel_SizeSpinner.Value app.Kernel_SizeSpinner.Value]);
                        app.P_I = Wiener_pic;
                        app.Image_preprocess.ImageSource = repmat(app.P_I, 1, 1, 3);
                    case "Local_laplacian"
                        LocalLap_pic = locallapfilt(app.I, app.SigmaEditField.Value, app.AlphaEditField.Value);
                        app.P_I = LocalLap_pic;
                        app.Image_preprocess.ImageSource = repmat(app.P_I, 1, 1, 3);
                end
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.file_name = '';
            [app.file_name, user_canceled] = imgetfile();
            if user_canceled == 0
                app.I = imread(app.file_name);
                app.Image_origin.ImageSource = repmat(app.I, 1, 1, 3);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.P_I)
                errordlg('Saving with empty image');
            else
                [fn, ~, ucancel] = imputfile();
                if ucancel == 0
                    imwrite(app.P_I, fn);
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Image_origin.ImageSource = '';
            app.Image_preprocess.ImageSource = '';
            app.Kernel_SizeSpinner.Value = 1;
            app.FilterDropDown.Value = 'Gaussian';
            app.AlphaEditField.Value = 0.1;
            app.SigmaEditField.Value = 0.1;
            app.LevelEditField.Value = 0.1;
            app.Radio_Otsu.Value = 1;
            app.file_name = '';
            app.StrelDropDown.Value = 'disk';
            app.RadiusSpinner.Value = 3;
            app.LocalRadiusSpinner.Value = 3;
            app.I = [];
            app.P_I = [];
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            if isempty(app.I)
                errordlg('Process with empty image');
            else
                if app.StrelDropDown.Value == "octagon" && mod(app.RadiusSpinner.Value, 3) ~= 0
                    errordlg('Radius must be a nonnegative multiple of 3');
                    return;
                end
                
                if app.Radio_Otsu.Value
                    BW = im2bw(app.I, graythresh(app.I));
                else
                    BW = im2bw(app.I, app.LevelEditField.Value);
                end
                SE = strel(app.StrelDropDown.Value, app.RadiusSpinner.Value);
                Openbw = imopen(~BW, SE);
                Fillbw = imfill(Openbw, 'holes');
                app.P_I = Fillbw;
                app.Image_preprocess.ImageSource = repmat(im2uint8(Fillbw), 1, 1, 3);
                
                stats = regionprops(Fillbw, 'BoundingBox', 'Area', 'Centroid', 'Image', 'PixelList');
                for i = 1:size(stats)
                    area = stats(i).Area;
                    if area > app.LowerBoundEditField.Value
                        img = stats(i).Image;
                        SE = strel('diamond', app.LocalRadiusSpinner.Value);
                        img = imopen(img, SE);
                        
                        col = stats(i).BoundingBox(1) + 0.5;
                        row = stats(i).BoundingBox(2) + 0.5;
                        
                        list = stats(i).PixelList;
                        for k = 1:size(list)
                            idx = list(k, 1);
                            idy = list(k, 2);
                            Fillbw(idy, idx) = img(idy-row+1, idx-col+1);
                        end
                    end
                end
                figure, imshow(Fillbw), title('Local Open');
                
                sum = 0;
                stats = regionprops(Fillbw, 'BoundingBox', 'Area', 'Centroid', 'Image', 'PixelList');
                centroids = cat(1, stats.Centroid);
                figure, imshow(app.img_origin);
                hold on
                for i = 1:size(stats)
                    area = stats(i).Area;
                    if area > app.LowerBoundEditField.Value && area < app.UpperBoundEditField.Value
                        rectangle('Position',[stats(i).BoundingBox],'LineWidth', 1.5,'LineStyle','-','EdgeColor','r'),
                        plot(centroids(i,1), centroids(i,2), 'b*');
                        sum = sum + 1;
                    end
                end
                hold off
                title(['Total Counts: ', num2str(sum)]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MIA_Final = uifigure('Visible', 'off');
            app.MIA_Final.Name = 'MIA_Final';
            app.TabGroup = uitabgroup(app.MIA_Final);
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.Pre_FigurePanel = uipanel(app.PreprocessingTab);
            app.Pre_FigurePanel.Title = 'Pre_Figure';
            app.Image_origin = uiimage(app.Pre_FigurePanel);
            app.Image_preprocess = uiimage(app.Pre_FigurePanel);
            app.Label = uilabel(app.Pre_FigurePanel);
            app.Label.Text = '==>';
            app.Pre_ParametersPanel = uipanel(app.PreprocessingTab);
            app.Pre_ParametersPanel.Title = 'Pre_Parameters';
            app.FilteringPanel = uipanel(app.Pre_ParametersPanel);
            app.FilteringPanel.Title = 'Filtering';
            app.SigmaLabel = uilabel(app.FilteringPanel);
            app.SigmaLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.SigmaEditField.Limits = [0.1 10];
            app.SigmaEditField.Value = 0.1;
            app.AlphaEditFieldLabel = uilabel(app.FilteringPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.AlphaEditField.Limits = [0.1 10];
            app.AlphaEditField.Value = 0.1;
            app.FilterDropDownLabel = uilabel(app.FilteringPanel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.FilteringPanel);
            app.FilterDropDown.Items = {'Gaussian', 'Mean', 'Median', 'Wiener', 'Local_laplacian'};
            app.FilterDropDown.Value = 'Gaussian';
            app.Kernel_SizeSpinnerLabel = uilabel(app.FilteringPanel);
            app.Kernel_SizeSpinnerLabel.Text = {'Kernel_Size'; ''};
            app.Kernel_SizeSpinner = uispinner(app.FilteringPanel);
            app.Kernel_SizeSpinner.Limits = [1 15];
            app.Kernel_SizeSpinner.Value = 1;
            app.FiltButton = uibutton(app.FilteringPanel, 'push');
            app.FiltButton.ButtonPushedFcn = createCallbackFcn(app, @FiltButtonPushed, true);
            app.FiltButton.Text = 'Filt';
            app.MorphologyPanel = uipanel(app.Pre_ParametersPanel);
            app.MorphologyPanel.Title = 'Morphology';
            app.BinarizeButtonGroup = uibuttongroup(app.MorphologyPanel);
            app.BinarizeButtonGroup.Title = 'Binarize';
            app.Radio_Otsu = uiradiobutton(app.BinarizeButtonGroup);
            app.Radio_Otsu.Text = 'Otsu';
            app.Radio_Otsu.Value = true;
            app.CustomizeButton = uiradiobutton(app.BinarizeButtonGroup);
            app.CustomizeButton.Text = 'Customize';
            app.OpenButton = uibutton(app.MorphologyPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.LevelEditFieldLabel = uilabel(app.MorphologyPanel);
            app.LevelEditFieldLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.MorphologyPanel, 'numeric');
            app.LevelEditField.Limits = [0 1];
            app.LevelEditField.Value = 0.1;
            app.RadiusSpinnerLabel = uilabel(app.MorphologyPanel);
            app.RadiusSpinnerLabel.Text = 'Radius';
            app.RadiusSpinner = uispinner(app.MorphologyPanel);
            app.RadiusSpinner.Limits = [3 15];
            app.RadiusSpinner.Value = 3;
            app.StrelDropDownLabel = uilabel(app.MorphologyPanel);
            app.StrelDropDownLabel.Text = 'Strel';
            app.StrelDropDown = uidropdown(app.MorphologyPanel);
            app.StrelDropDown.Items = {'disk', 'diamond', 'square', 'octagon'};
            app.StrelDropDown.Value = 'disk';
            app.SegmentationPanel = uipanel(app.MorphologyPanel);
            app.SegmentationPanel.Title = 'Segmentation';
            app.UpperBoundEditFieldLabel = uilabel(app.SegmentationPanel);
            app.UpperBoundEditFieldLabel.Text = 'UpperBound';
            app.UpperBoundEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.UpperBoundEditField.Limits = [0 30000];
            app.UpperBoundEditField.ValueDisplayFormat = '%11.5g';
            app.UpperBoundEditField.Value = 30000;
            app.LowerBoundEditFieldLabel = uilabel(app.SegmentationPanel);
            app.LowerBoundEditFieldLabel.Text = 'LowerBound';
            app.LowerBoundEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.LowerBoundEditField.Limits = [0 3000];
            app.LowerBoundEditField.Value = 1000;
            app.LocalBoundEditFieldLabel = uilabel(app.SegmentationPanel);
            app.LocalBoundEditFieldLabel.Text = 'LocalBound';
            app.LocalBoundEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.LocalBoundEditField.Limits = [0 30000];
            app.LocalBoundEditField.ValueDisplayFormat = '%11.5g';
            app.LocalBoundEditField.Value = 12000;
            app.LocalRadiusSpinnerLabel = uilabel(app.SegmentationPanel);
            app.LocalRadiusSpinnerLabel.Text = 'Local Radius';
            app.LocalRadiusSpinner = uispinner(app.SegmentationPanel);
            app.LocalRadiusSpinner.Limits = [3 20];
            app.LocalRadiusSpinner.Value = 10;
            app.OthersPanel = uipanel(app.Pre_ParametersPanel);
            app.OthersPanel.Title = 'Others';
            app.LoadButton = uibutton(app.OthersPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.OthersPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.OthersPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.MIA_Final.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FirstApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MIA_Final)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MIA_Final)
        end
    end
end